classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        SimulationDropDown       matlab.ui.control.DropDown
        SimulationDropDownLabel  matlab.ui.control.Label
        title                    matlab.ui.control.Label
        ModulationDropDown       matlab.ui.control.DropDown
        ZmodelDropDown           matlab.ui.control.DropDown
        RunButton                matlab.ui.control.Button
        ZmodelDropDownLabel      matlab.ui.control.Label
        ModulationDropDownLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, STC_modulation_label, Z_model_label, simulation_label)
            app.ModulationDropDown.Items = STC_modulation_label;
            app.ZmodelDropDown.Items = Z_model_label;
            app.SimulationDropDown.Items = simulation_label;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            simulation_type = app.SimulationDropDown.Value;
            

            switch simulation_type
                case 'TM'
                    STC = SpatioTemporalCell(Beam(), Piezo().bindShunt(Shunt(app.ZmodelDropDown.Value), 0));
                    run("analysis\TM\Main_TM.m")
                case 'PWEM'
                    [STC, modulation] = assemble_STC(app.ModulationDropDown.Value, app.ZmodelDropDown.Value);
                    run("analysis\PWEM\Main_PWEM.m")
                case 'FDTD'
                    [STC, modulation] = assemble_STC(app.ModulationDropDown.Value, app.ZmodelDropDown.Value);
                    run("analysis\FDTD\Main_FDTD.m")
                otherwise
                    error(['Required simulation not found (' simulation_type ')'])
            end
        end

        % Callback function
        function ExportButtonPushed(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ModulationDropDownLabel = uilabel(app.GridLayout);
            app.ModulationDropDownLabel.Text = 'Modulation';
            app.ZmodelDropDownLabel = uilabel(app.GridLayout);
            app.ZmodelDropDownLabel.Text = 'Z model';
            app.RunButton = uibutton(app.GridLayout, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ZmodelDropDown = uidropdown(app.GridLayout);
            app.ModulationDropDown = uidropdown(app.GridLayout);
            app.title = uilabel(app.GridLayout);
            app.title.Text = 'Simulation launcher';
            app.SimulationDropDownLabel = uilabel(app.GridLayout);
            app.SimulationDropDownLabel.Text = 'Simulation';
            app.SimulationDropDown = uidropdown(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end